classdef GUI_Proto < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CapstoneGroupHUIFigure  matlab.ui.Figure
        EventsListBoxLabel      matlab.ui.control.Label
        EventsListBox           matlab.ui.control.ListBox
        TabGroup                matlab.ui.container.TabGroup
        SignalsTab              matlab.ui.container.Tab
        UIAxes                  matlab.ui.control.UIAxes
        SignalsPanel            matlab.ui.container.Panel
        Signal1CheckBox         matlab.ui.control.CheckBox
        Signal2CheckBox         matlab.ui.control.CheckBox
        Signal3CheckBox         matlab.ui.control.CheckBox
        FFTTab                  matlab.ui.container.Tab
        UIAxes2                 matlab.ui.control.UIAxes
        SignalsPanel_2          matlab.ui.container.Panel
        Signal1CheckBox_2       matlab.ui.control.CheckBox
        Signal2CheckBox_2       matlab.ui.control.CheckBox
        Signal3CheckBox_2       matlab.ui.control.CheckBox
        CalculationsTab         matlab.ui.container.Tab
        UITable                 matlab.ui.control.Table
    end


    properties (Access = private)
        p1;
        p2;
        p3;
        p4;
        p5;
        p6;
        info;
    end


    methods (Access = private)

        % Code that executes after component creation
        function startup(app)
            app.info = Main.Start();
            
            a = app.info(1).filter1;
            b = app.info(1).filter2;
            c = app.info(1).filter3;
            
            d = app.info(1).fourier1;
            e = app.info(1).fourier2;
            f = app.info(1).fourier3;
            
            app.p1 = plot(app.UIAxes,a);
            app.p2 = plot(app.UIAxes,b);
            app.p3 = plot(app.UIAxes,c);
            
            app.p4 = plot(app.UIAxes2,d);
            app.p5 = plot(app.UIAxes2,e);
            app.p6 = plot(app.UIAxes2,f);
            
            % eventName = zeros(length(info),1);
            for i = 1:length(app.info)
                eventName(i) = app.info(i).name;
                itemsData(i) = i;
            end
            
            % eventName = {'event1','event2','event3','event4'};
            app.EventsListBox.Items = eventName;
            app.EventsListBox.ItemsData = itemsData();
            
            app.UITable.Data = {'Event 1',0,0,0,0,0,0;'Event 2',0,0,0,0,0,0};
        end

        % Value changed function: EventsListBox, Signal1CheckBox, 
        % Signal1CheckBox_2, Signal2CheckBox, Signal2CheckBox_2, 
        % Signal3CheckBox, Signal3CheckBox_2
        function ValueChanged(app, event)
            value = get(app.EventsListBox, 'Value');
            cla(app.UIAxes);
            cla(app.UIAxes2);
            
            %a = app.info(value).filter1;
            %b = app.info(value).filter2;
            %c = app.info(value).filter3;
            
            %d = app.info(value).fourier1;
            %e = app.info(value).fourier2;
            %f = app.info(value).fourier3;
            
            app.p1 = plot(app.UIAxes,app.info(value).filter1);
            app.p2 = plot(app.UIAxes,app.info(value).filter2);
            app.p3 = plot(app.UIAxes,app.info(value).filter3);
            
            app.p4 = plot(app.UIAxes2,app.info(value).fourier1);
            app.p5 = plot(app.UIAxes2,app.info(value).fourier2);
            app.p6 = plot(app.UIAxes2,app.info(value).fourier3);
            
            value = app.Signal1CheckBox.Value;
            if value == 0
                app.p1.set('visible','off');
            else
                app.p1.set('visible','on');
            end
            
            value = app.Signal2CheckBox.Value;
            if value == 0
                app.p2.set('visible','off');
            else
                app.p2.set('visible','on');
            end
            
            value = app.Signal3CheckBox.Value;
            if value == 0
                app.p3.set('visible','off');
            else
                app.p3.set('visible','on');
            end
            
            value = app.Signal1CheckBox_2.Value;
            if value == 0
                app.p4.set('visible','off');
            else
                app.p4.set('visible','on');
            end
            
            value = app.Signal2CheckBox_2.Value;
            if value == 0
                app.p5.set('visible','off');
            else
                app.p5.set('visible','on');
            end
            
            value = app.Signal3CheckBox_2.Value;
            if value == 0
                app.p6.set('visible','off');
            else
                app.p6.set('visible','on');
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CapstoneGroupHUIFigure = uifigure;
            app.CapstoneGroupHUIFigure.Name = 'Capstone Group H';
            setAutoResize(app, app.CapstoneGroupHUIFigure, true)
            app.EventsListBoxLabel = uilabel(app.CapstoneGroupHUIFigure);
            app.EventsListBoxLabel.Text = 'Events';
            app.EventsListBox = uilistbox(app.CapstoneGroupHUIFigure);
            app.EventsListBox.Items = {};
            app.EventsListBox.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EventsListBox.Value = {};
            app.TabGroup = uitabgroup(app.CapstoneGroupHUIFigure);
            app.SignalsTab = uitab(app.TabGroup);
            app.SignalsTab.Title = 'Signals';
            app.SignalsTab.Units = 'pixels';
            app.UIAxes = uiaxes(app.SignalsTab);
            title(app.UIAxes, 'Title');
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            app.SignalsPanel = uipanel(app.SignalsTab);
            app.SignalsPanel.Title = 'Signals';
            app.Signal1CheckBox = uicheckbox(app.SignalsPanel);
            app.Signal1CheckBox.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.Signal1CheckBox.Text = 'Signal 1';
            app.Signal1CheckBox.Value = true;
            app.Signal2CheckBox = uicheckbox(app.SignalsPanel);
            app.Signal2CheckBox.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.Signal2CheckBox.Text = 'Signal 2';
            app.Signal2CheckBox.Value = true;
            app.Signal3CheckBox = uicheckbox(app.SignalsPanel);
            app.Signal3CheckBox.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.Signal3CheckBox.Text = 'Signal 3';
            app.Signal3CheckBox.Value = true;
            app.FFTTab = uitab(app.TabGroup);
            app.FFTTab.Title = 'FFT';
            app.FFTTab.Units = 'pixels';
            app.UIAxes2 = uiaxes(app.FFTTab);
            title(app.UIAxes2, 'Title');
            xlabel(app.UIAxes2, 'X');
            ylabel(app.UIAxes2, 'Y');
            app.SignalsPanel_2 = uipanel(app.FFTTab);
            app.SignalsPanel_2.Title = 'Signals';
            app.Signal1CheckBox_2 = uicheckbox(app.SignalsPanel_2);
            app.Signal1CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.Signal1CheckBox_2.Text = 'Signal 1';
            app.Signal1CheckBox_2.Value = true;
            app.Signal2CheckBox_2 = uicheckbox(app.SignalsPanel_2);
            app.Signal2CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.Signal2CheckBox_2.Text = 'Signal 2';
            app.Signal2CheckBox_2.Value = true;
            app.Signal3CheckBox_2 = uicheckbox(app.SignalsPanel_2);
            app.Signal3CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.Signal3CheckBox_2.Text = 'Signal 3';
            app.Signal3CheckBox_2.Value = true;
            app.CalculationsTab = uitab(app.TabGroup);
            app.CalculationsTab.Title = 'Calculations';
            app.CalculationsTab.Units = 'pixels';
            app.UITable = uitable(app.CalculationsTab);
            app.UITable.ColumnName = {'Name'; 'Max'; 'Min'; 'Average'; 'Median'; 'Std Dev'; 'Variance'};
            app.UITable.RowName = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI_Proto()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CapstoneGroupHUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CapstoneGroupHUIFigure)
        end
    end
end